classdef Voiceanalyzer11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PlayButton          matlab.ui.control.Button
        HZEditFieldLabel    matlab.ui.control.Label
        HZEditField         matlab.ui.control.NumericEditField
        tmaxEditField       matlab.ui.control.NumericEditField
        tmaxEditFieldLabel  matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        StopButton          matlab.ui.control.Button
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        recObj              % To store the audiorecorder object
        Storedata           % To store the recorded audio data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
         % Get user inputs for maximum recording time and sampling frequency
            tmax = app.tmaxEditField.Value;
            fs = app.HZEditField.Value;

            % Set default values if not provided or if the input is empty
            if isempty(tmax)
                tmax = 5;  % Default recording time in seconds
            end

            if isempty(fs)
                fs = 44100;  % Default sampling frequency in Hz
            end

            % Validate user inputs
            if tmax <= 0 || fs <= 0
                errordlg('Invalid input. Please enter positive values for maximum recording time and sampling frequency.', 'Error');
                return;
            end

            % Record audio
            app.recObj = audiorecorder(fs, 16, 1); % 16 bits, 1 channel (mono)
            disp('Start speaking...');
            recordblocking(app.recObj, tmax);
            disp('Recording finished.');
            
            % Store recorded data
            app.Storedata = getaudiodata(app.recObj);

            % Get recorded data
            voiceData = getaudiodata(app.recObj);

            % Plot the voice signal in time domain
            plot(app.UIAxes, 0:1/fs:(length(voiceData)-1)/fs, voiceData);
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Voice Signal in Time Domain');

            % Plot the voice signal in frequency domain
            N = length(voiceData);
            frequencies = linspace(0, fs/2, N/2);
            voiceFFT = abs(fft(voiceData)/N);
            plot(app.UIAxes2, frequencies, 20*log10(voiceFFT(1:N/2)));
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Magnitude (dB)');
            title(app.UIAxes2, 'Voice Signal in Frequency Domain');

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
    % Stop recording
    % Stop recording immediately
    if isvalid(app.recObj) && isrecording(app.recObj)
        stop(app.recObj);
        disp('Recording stopped.');
    end


    % Display the recorded data and update the plots
    voiceData = getaudiodata(app.recObj);

    % Plot the voice signal in time domain
    plot(app.UIAxes, 0:1/app.HZEditField.Value:(length(voiceData)-1)/app.HZEditField.Value, voiceData);
    xlabel(app.UIAxes, 'Time (s)');
    ylabel(app.UIAxes, 'Amplitude');
    title(app.UIAxes, 'Voice Signal in Time Domain');

    % Plot the voice signal in frequency domain
    N = length(voiceData);
    frequencies = linspace(0, app.HZEditField.Value/2, N/2);
    voiceFFT = abs(fft(voiceData)/N);
    plot(app.UIAxes2, frequencies, 20*log10(voiceFFT(1:N/2)));
    xlabel(app.UIAxes2, 'Frequency (Hz)');
    ylabel(app.UIAxes2, 'Magnitude (dB)');
    title(app.UIAxes2, 'Voice Signal in Frequency Domain');

     
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % Play the recorded voice
            if ~isempty(app.Storedata)
                sound(app.Storedata, app.HZEditField.Value);
                disp('Playing recorded voice...');
            else
                disp('No recorded voice to play. Press "Start" to record first.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'T')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'HZ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.tmaxEditFieldLabel = uilabel(app.UIFigure);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.HZEditField = uieditfield(app.UIFigure, 'numeric');
            app.HZEditFieldLabel = uilabel(app.UIFigure);
            app.HZEditFieldLabel.Text = 'HZ';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Voiceanalyzer11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end